classdef oct_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PlotArbitrarySliceButton  matlab.ui.control.Button
        PlotVolumeButton          matlab.ui.control.Button
        ZheightSlider             matlab.ui.control.Slider
        ZheightSliderLabel        matlab.ui.control.Label
        ImportVolumeButton        matlab.ui.control.Button
        RotationPanel             matlab.ui.container.Panel
        yaxisEditField            matlab.ui.control.NumericEditField
        yaxisEditFieldLabel       matlab.ui.control.Label
        zaxisEditField            matlab.ui.control.NumericEditField
        zaxisEditFieldLabel       matlab.ui.control.Label
        xaxisEditField            matlab.ui.control.NumericEditField
        xaxisEditFieldLabel       matlab.ui.control.Label
        RotateButton              matlab.ui.control.Button
        AngleSpinner              matlab.ui.control.Spinner
        AngleSpinnerLabel         matlab.ui.control.Label
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function [handle, oct_volume] = openFile(app, data);
           [handle, oct_volume] = oct_load_volume(data);
        end
        
        function [hslice] = createSurface(app, oct_volume, value)
            [hslice] = oct_create_surface(oct_volume,value);
        end
        
        function [xd, yd, zd] = rotateSurface(app, hslice, theta, x, y, z, oct_volume)
            [xd, yd,zd] = oct_rotate_surface(hslice, theta, x, y, z, oct_volume);
        end
        
        function [h] = plotSurface(app, oct_volume, xd,yd, zd)
            h = oct_plot_surface(oct_volume, xd,yd, zd);
        end
        
        function  [horizontal, vertical] = plotScalebar(app, h, theta, handle)
            [horizontal, vertical] = oct_scalebar(h, theta, handle);
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportVolumeButton
        function ImportVolumeButtonPushed(app, event)
            global oct_volume
            global handle
            
            [handle, oct_volume] = openFile(app,'testdata2.oct');
            msgbox('OCT volume imported')
        end

        % Value changed function: ZheightSlider
        function ZheightSliderValueChanged(app, event)
            global hslice
            global oct_volume
            
            value = app.ZheightSlider.Value;
            
            hslice = createSurface(app, oct_volume, value);
            msgbox('Z height set')
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            global hslice
            global oct_volume
            global xd
            global yd
            global zd
            global theta
             
            theta = app.AngleSpinner.Value;
            x_axis = app.xaxisEditField.Value;
            y_axis = app.yaxisEditField.Value;
            z_axis = app.zaxisEditField.Value;
            
            % PROBLEM WITH AXES
            [xd,yd,zd] = rotateSurface(app, hslice, theta, x_axis, y_axis, z_axis, oct_volume);
            msgbox('Slice rotated')
        end

        % Value changed function: AngleSpinner
        function AngleSpinnerValueChanged(app, event)
            global angle
        
            angle = app.AngleSpinner.Value;
        end

        % Button pushed function: PlotVolumeButton
        function PlotVolumeButtonPushed(app, event)
            global oct_volume
            global xd
            global yd
            global zd
            global h
            
            colormap(app.UIAxes, turbo)
            h = slice(app.UIAxes, oct_volume,xd,yd,zd);
            h.FaceColor = 'interp';
            h.EdgeColor = 'none';
            h.DiffuseStrength = 0.8;
            
            xlabel(app.UIAxes, 'x-axis')
            ylabel(app.UIAxes, 'y-axis')
            zlabel(app.UIAxes, 'z-axis')
            
            %app.UIAxes = uiaxes(); 
            %plot(app.UIAxes,h,[0,1], '-o')
            
            %app.UIAxes.xlim([0, size(h.XData,1)])
            %app.UIAXes.ylim([0, size(h.YData,1)])  
            
            msgbox('Volume plotted')
        end

        % Button pushed function: PlotArbitrarySliceButton
        function PlotArbitrarySliceButtonPushed(app, event)
            global oct_volume
            global xd
            global yd
            global zd
            global handle
            global h
            global theta
            
            colormap(app.UIAxes_2, turbo)
            h = slice(app.UIAxes_2, oct_volume,xd,yd,zd);
            h.FaceColor = 'interp';
            h.EdgeColor = 'none';
            h.DiffuseStrength = 0.8;
         
            % ENTER LENGTH IN GUI BY USER 
            
            [horizontal, vertical] = plotScalebar(app, h, theta, handle)
                        
            % horizontal scale bar
            plot3(app.UIAxes_2, [horizontal(1,1),horizontal(1,2)],[horizontal(2,1),horizontal(2,2)],[horizontal(3,1), horizontal(3,2)],'k', 'LineWidth', 1);
            
            % vertical scale bar
            plot3(app.UIAxes_2, [vertical(1,2),vertical(1,1)], [vertical(2,1),vertical(2,2)], [vertical(3,1),vertical(3,2)], 'k', 'LineWidth', 1);
            
            text(app.UIAxes_2, vertical(1,1), vertical(2,1)-10, vertical(3,1), '500 um', 'HorizontalAlignment','center');
            %hold off
%             xlabel('x-axis')
%             ylabel('y-axis')
%             zlabel('z-axis')
                                  
            app.UIAxes_2.View = [0,90]
            
            %app.UIAxes_2.xlim([0, size(h.XData,1)])
            %app.UIAXes_2.ylim([0, size(h.YData,1)]) 
            
            msgbox('Arbitrary slice plotted')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '3D Volume')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Arbitrary slice')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.RotationPanel = uipanel(app.UIFigure);
            app.RotationPanel.Title = 'Rotation';
            app.AngleSpinnerLabel = uilabel(app.RotationPanel);
            app.AngleSpinnerLabel.Text = 'Angle';
            app.AngleSpinner = uispinner(app.RotationPanel);
            app.AngleSpinner.ValueChangedFcn = createCallbackFcn(app, @AngleSpinnerValueChanged, true);
            app.RotateButton = uibutton(app.RotationPanel, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.xaxisEditFieldLabel = uilabel(app.RotationPanel);
            app.xaxisEditFieldLabel.Text = 'x-axis';
            app.xaxisEditField = uieditfield(app.RotationPanel, 'numeric');
            app.zaxisEditFieldLabel = uilabel(app.RotationPanel);
            app.zaxisEditFieldLabel.Text = 'z-axis';
            app.zaxisEditField = uieditfield(app.RotationPanel, 'numeric');
            app.yaxisEditFieldLabel = uilabel(app.RotationPanel);
            app.yaxisEditFieldLabel.Text = 'y-axis';
            app.yaxisEditField = uieditfield(app.RotationPanel, 'numeric');
            app.ImportVolumeButton = uibutton(app.UIFigure, 'push');
            app.ImportVolumeButton.ButtonPushedFcn = createCallbackFcn(app, @ImportVolumeButtonPushed, true);
            app.ImportVolumeButton.Text = 'Import Volume';
            app.ZheightSliderLabel = uilabel(app.UIFigure);
            app.ZheightSliderLabel.Text = 'Z height';
            app.ZheightSlider = uislider(app.UIFigure);
            app.ZheightSlider.ValueChangedFcn = createCallbackFcn(app, @ZheightSliderValueChanged, true);
            app.PlotVolumeButton = uibutton(app.UIFigure, 'push');
            app.PlotVolumeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotVolumeButtonPushed, true);
            app.PlotVolumeButton.Text = 'Plot Volume';
            app.PlotArbitrarySliceButton = uibutton(app.UIFigure, 'push');
            app.PlotArbitrarySliceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotArbitrarySliceButtonPushed, true);
            app.PlotArbitrarySliceButton.Text = 'Plot Arbitrary Slice';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oct_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end